classdef EnergyDispersionRelation2DGraphite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        energyDispersionRelation  matlab.ui.control.UIAxes
        aSliderLabel              matlab.ui.control.Label
        aSlider                   matlab.ui.control.Slider
        sSliderLabel              matlab.ui.control.Label
        sSlider                   matlab.ui.control.Slider
        teVSliderLabel            matlab.ui.control.Label
        tSlider                   matlab.ui.control.Slider
        ResetButton               matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        whilethefunctionkisgivenbyLabel  matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Image3                    matlab.ui.control.Image
        e2pSliderLabel            matlab.ui.control.Label
        e2pSlider                 matlab.ui.control.Slider
        unitCell                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a;
        e2p;
        s;
        t;        
        k_x;
        k_y;
        omega;
        Eg2Dn;
        Eg2Dp;
    end
    
    methods (Access = public)
        
        function [] = highSimmetryPoints(app)
            %find high simmetry point gamma
            text(app.energyDispersionRelation, 0,0, max(app.Eg2Dp,[], 'all'), '$\Gamma$', 'VerticalAlignment', 'bottom', 'FontSize', 16, 'Interpreter','latex');
            %find high simmetry points 'k'
            min_E = min(app.Eg2Dp,[], 'all');
            for i = 1:length(app.k_y)
               for j = 1:length(app.k_x)
                   if (app.Eg2Dp (i,j) == min_E)
                       if(app.k_x(j)<0)
                           text(app.energyDispersionRelation, app.k_x(j), app.k_y(i), app.Eg2Dp (i,j), '$K$', 'VerticalAlignment', 'middle', 'FontSize', 16, 'HorizontalAlignment', 'right', 'Interpreter','latex');
                       else
                           text(app.energyDispersionRelation, app.k_x(j), app.k_y(i), app.Eg2Dp (i,j), '$K$', 'VerticalAlignment', 'middle', 'FontSize', 16, 'HorizontalAlignment', 'left', 'Interpreter','latex');
                       end
                   end
               end
            end
            text(app.energyDispersionRelation, 0, max(app.k_y), 0, '$K$', 'VerticalAlignment', 'bottom', 'FontSize', 16, 'HorizontalAlignment', 'center', 'Interpreter','latex');
            text(app.energyDispersionRelation, 0, -max(app.k_y), 0, '$K$', 'VerticalAlignment', 'top', 'FontSize', 16, 'HorizontalAlignment', 'center', 'Interpreter','latex');
            %find high simmetry points 'M'
            max_kx = max(app.k_x); 
            omega_temp = sqrt(1+4*cos(sqrt(3)*max_kx*app.a/2)*cos(0*app.a/2)+ 4*cos(0*app.a/2)*cos(0*app.a/2));
            Eg2Dp_M_point = (app.e2p - app.t*omega_temp)/(1 - app.s*omega_temp);
            text(app.energyDispersionRelation, max_kx, 0, Eg2Dp_M_point, '$M$', 'VerticalAlignment', 'top', 'FontSize', 16, 'HorizontalAlignment', 'left', 'Interpreter','latex');
            text(app.energyDispersionRelation, -max_kx, 0, Eg2Dp_M_point, '$M$', 'VerticalAlignment', 'top', 'FontSize', 16, 'HorizontalAlignment', 'right', 'Interpreter','latex');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.energyDispersionRelation.cla;
                     
            app.aSlider.Value = 1;
            app.e2pSlider.Value = 0;
            app.tSlider.Value = -3.033;
            app.sSlider.Value = 0.129;
            app.a = app.aSlider.Value;
            app.e2p = app.e2pSlider.Value;
            app.t = app.tSlider.Value;
            app.s = app.sSlider.Value;
            
            app.Eg2Dn = zeros (100, 100);
            app.Eg2Dp = zeros (100, 100);
        
            app.k_x = linspace(-2*pi/(sqrt(3)*app.a), 2*pi/(sqrt(3)*app.a));
            %k_y = -3/2*pi/a : 0.1 : 3/2*pi/a;
            app.k_y = linspace(-2*3/(sqrt(2)*app.a), 2*3/(sqrt(2)*app.a));
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.omega (i,j) = sqrt(1+4*cos(sqrt(3)*app.k_x(j)*app.a/2)*cos(app.k_y(i)*app.a/2)+ 4*cos(app.k_y(i)*app.a/2)*cos(app.k_y(i)*app.a/2));
                    app.Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    app.Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            title(app.energyDispersionRelation, 'Energy Dispersion Relation 2D Graphite');
            surf(app.energyDispersionRelation, app.k_x, app.k_y, app.Eg2Dn);
            hold(app.energyDispersionRelation, 'on');
            surf(app.energyDispersionRelation, app.k_x, app.k_y, app.Eg2Dp);
            xlabel (app.energyDispersionRelation, '$k_x$', 'Interpreter','latex');
            ylabel (app.energyDispersionRelation, '$k_y$', 'Interpreter','latex');
            zlabel (app.energyDispersionRelation, '$E~[eV]$', 'Interpreter','latex', 'Rotation', 0, 'VerticalAlignment','middle');
            colorbar(app.energyDispersionRelation, 'eastoutside');
            
            highSimmetryPoints(app);
            
            x=app.a*sqrt(3)*[-0.5 0 0.5 0.5 0 -0.5 -0.5];
            y=app.a*[0.5 1 0.5 -0.5 -1 -0.5 0.5];    
            plot(app.unitCell, x,y, 'b');
            xlim (app.unitCell, [-max(app.aSlider.Limits)  max(app.aSlider.Limits)]);
            ylim (app.unitCell, [-max(app.aSlider.Limits) max(app.aSlider.Limits)]);
            xlabel (app.unitCell, '$x~[nm]$', 'Rotation',0, 'Interpreter',"latex", 'HorizontalAlignment','center');
            ylabel (app.unitCell, '$y~[nm]$', 'Rotation',0, 'Interpreter',"latex", 'HorizontalAlignment','right');
            text(app.unitCell, 0,0, '$\Gamma$', 'Interpreter','latex');
            text(app.unitCell, app.a*sqrt(3)*0.5, 0.5, '$K$', 'HorizontalAlignment', 'left', 'Interpreter','latex');
            text(app.unitCell, app.a*sqrt(3)*0.5, 0, '$M$', 'HorizontalAlignment', 'left', 'Interpreter','latex');
            hold (app.unitCell, "on");
            arrow_x = [0 x(3)];
            arrow_y = [0   y(3)];
            plot (app.unitCell, arrow_x, arrow_y, 'b');
            text (app.unitCell, x(3)/2, y(3)/2, '$a$', 'Interpreter', 'latex', 'VerticalAlignment', 'bottom');
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            app.energyDispersionRelation.cla;
            app.unitCell.cla;
            
            app.a = event.Value;
            app.k_x = linspace(-2*pi/(sqrt(3)*app.a), 2*pi/(sqrt(3)*app.a));
            app.k_y = linspace(-2*3/(sqrt(2)*app.a), 2*3/(sqrt(2)*app.a));
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.omega (i,j) = sqrt(1+4*cos(sqrt(3)*app.k_x(j)*app.a/2)*cos(app.k_y(i)*app.a/2)+ 4*cos(app.k_y(i)*app.a/2)*cos(app.k_y(i)*app.a/2));
                    app.Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    app.Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersionRelation, app.k_x, app.k_y,  app.Eg2Dn);
            hold(app.energyDispersionRelation, 'on');
            surf(app.energyDispersionRelation, app.k_x, app.k_y, app.Eg2Dp);
            
            highSimmetryPoints(app);
            
            x=app.a*sqrt(3)*[-0.5 0 0.5 0.5 0 -0.5 -0.5];
            y=app.a*[0.5 1 0.5 -0.5 -1 -0.5 0.5];    
            plot(app.unitCell, x, y, 'b');
            xlim (app.unitCell, [-max(app.aSlider.Limits) max(app.aSlider.Limits)]);
            ylim (app.unitCell, [-max(app.aSlider.Limits) max(app.aSlider.Limits)]);
            text(app.unitCell, 0,0, '$\Gamma$', 'Interpreter','latex');
            text(app.unitCell, app.a*sqrt(3)*0.5, 0.5, '$K$', 'HorizontalAlignment', 'left', 'Interpreter','latex');
            text(app.unitCell, app.a*sqrt(3)*0.5, 0, '$M$', 'HorizontalAlignment', 'left', 'Interpreter','latex');
            hold (app.unitCell, "on");
            arrow_x = [0 x(3)];
            arrow_y = [0   y(3)];
            plot (app.unitCell, arrow_x, arrow_y, 'b');
            text (app.unitCell, x(3)/2, y(3)/2, '$a$', 'Interpreter', 'latex', 'VerticalAlignment', 'bottom');
        end

        % Value changing function: sSlider
        function sSliderValueChanging(app, event)
            app.energyDispersionRelation.cla;
            
            app.s = event.Value;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    app.Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersionRelation, app.k_x, app.k_y,  app.Eg2Dn);
            hold(app.energyDispersionRelation, 'on');
            surf(app.energyDispersionRelation, app.k_x, app.k_y, app.Eg2Dp);
            
            highSimmetryPoints(app);
        end

        % Value changing function: tSlider
        function tSliderValueChanging(app, event)
            app.energyDispersionRelation.cla;
            
            app.t = event.Value;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    app.Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersionRelation, app.k_x, app.k_y,  app.Eg2Dn);
            hold(app.energyDispersionRelation, 'on');
            surf(app.energyDispersionRelation, app.k_x, app.k_y, app.Eg2Dp);
            
            highSimmetryPoints(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            startupFcn(app);
        end

        % Value changing function: e2pSlider
        function e2pSliderValueChanging(app, event)
            app.energyDispersionRelation.cla;
            
            app.e2p = event.Value;
            for i = 1:length(app.k_y)
                for j = 1:length(app.k_x)
                    app.Eg2Dn (i,j) = (app.e2p + app.t*app.omega (i,j))/(1 + app.s*app.omega (i,j));
                    app.Eg2Dp (i,j) = (app.e2p - app.t*app.omega (i,j))/(1 - app.s*app.omega (i,j));
                end
            end
            surf(app.energyDispersionRelation, app.k_x, app.k_y,  app.Eg2Dn);
            hold(app.energyDispersionRelation, 'on');
            surf(app.energyDispersionRelation, app.k_x, app.k_y, app.Eg2Dp);
            
            highSimmetryPoints(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.energyDispersionRelation = uiaxes(app.UIFigure);
            title(app.energyDispersionRelation, 'Energy dispersion relation')
            xlabel(app.energyDispersionRelation, 'X')
            ylabel(app.energyDispersionRelation, 'Y')
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0.5 1];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 1;
            app.sSliderLabel = uilabel(app.UIFigure);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.UIFigure);
            app.sSlider.Limits = [0 0.3];
            app.sSlider.ValueChangingFcn = createCallbackFcn(app, @sSliderValueChanging, true);
            app.sSlider.Value = 0.129;
            app.teVSliderLabel = uilabel(app.UIFigure);
            app.teVSliderLabel.Text = 't [eV]';
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.Limits = [-5 0];
            app.tSlider.ValueChangingFcn = createCallbackFcn(app, @tSliderValueChanging, true);
            app.tSlider.Value = -3.033;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'The energy dispersion relations is given by:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'w(k).png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'E1.png';
            app.whilethefunctionkisgivenbyLabel = uilabel(app.UIFigure);
            app.whilethefunctionkisgivenbyLabel.Text = ' while the function ω(k) is given by:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ' If s = 0 the relation becomes:';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'E2.png';
            app.e2pSliderLabel = uilabel(app.UIFigure);
            app.e2pSliderLabel.Text = 'e2p';
            app.e2pSlider = uislider(app.UIFigure);
            app.e2pSlider.Limits = [0 1];
            app.e2pSlider.ValueChangingFcn = createCallbackFcn(app, @e2pSliderValueChanging, true);
            app.unitCell = uiaxes(app.UIFigure);
            title(app.unitCell, 'Unit cell')
            xlabel(app.unitCell, 'x')
            ylabel(app.unitCell, 'y')
            app.unitCell.XLim = [-1 1];
            app.unitCell.YLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnergyDispersionRelation2DGraphite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end